classdef DemoTrainedNetwork < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        NextButton                      matlab.ui.control.Button
        ColormapButtonGroup             matlab.ui.container.ButtonGroup
        GrayButton                      matlab.ui.control.RadioButton
        HotButton                       matlab.ui.control.RadioButton
        ParulaButton                    matlab.ui.control.RadioButton
        NetworkInformationTextAreaLabel  matlab.ui.control.Label
        NetworkInformationTextArea      matlab.ui.control.TextArea
        QuitButton                      matlab.ui.control.Button
        RightPanel                      matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
        NetworkPredictionTextAreaLabel  matlab.ui.control.Label
        NetworkPredictionTextArea       matlab.ui.control.TextArea
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global test
            [~,test] = loadData3();
            global w b dim
            load trainedNetTwo.mat w b dim;
            stat = testNetwork3(test,w,b,dim);
            app.NetworkInformationTextArea.Value = ['Network Name: trainedNetTwo',newline...
                'Dimensions: ',num2str(dim(1)),'x',num2str(dim(2)),'x',...
                num2str(dim(3)),newline,'Accuracy: ',num2str(stat),'%'];
            shuffleOrder = randperm(size(test,1));
            test = test(shuffleOrder,:); %shuffle
            initImage = reshape(test{1,1},28,28)';
            imagesc(app.UIAxes,initImage);
            colormap(app.UIAxes,"gray");
            global digit
            digit = 1;
        end

        % Selection changed function: ColormapButtonGroup
        function ColormapButtonGroupSelectionChanged(app, event)
            selectedButton = app.ColormapButtonGroup.SelectedObject;
            switch selectedButton
                case app.GrayButton
                    colormap(app.UIAxes,"gray");
                case app.HotButton
                    colormap(app.UIAxes,"hot");
                case app.ParulaButton
                    colormap(app.UIAxes,"parula");
            end
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            global digit test w b dim
            digit = digit + 1;
            currentImage = test{digit,1};
            image = transpose(reshape(currentImage,28,28));
            imagesc(app.UIAxes,image);
            guess = identifydigit3(currentImage,w,b,dim);
            if guess == 10
                guess = 0;
            end
            app.NetworkPredictionTextArea.Value = num2str(guess);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            clear global digit
            clear global test
            clear global w
            clear global b
            clear global dim
        end

        % Button pushed function: QuitButton
        function QuitButtonPushed(app, event)
            delete(app)
            clear global digit
            clear global test
            clear global w
            clear global b
            clear global dim
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.NextButton = uibutton(app.LeftPanel, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.ColormapButtonGroup = uibuttongroup(app.LeftPanel);
            app.ColormapButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ColormapButtonGroupSelectionChanged, true);
            app.ColormapButtonGroup.Title = 'Colormap';
            app.GrayButton = uiradiobutton(app.ColormapButtonGroup);
            app.GrayButton.Text = 'Gray';
            app.GrayButton.Value = true;
            app.HotButton = uiradiobutton(app.ColormapButtonGroup);
            app.HotButton.Text = 'Hot';
            app.ParulaButton = uiradiobutton(app.ColormapButtonGroup);
            app.ParulaButton.Text = 'Parula';
            app.NetworkInformationTextAreaLabel = uilabel(app.LeftPanel);
            app.NetworkInformationTextAreaLabel.Text = 'Network Information';
            app.NetworkInformationTextArea = uitextarea(app.LeftPanel);
            app.QuitButton = uibutton(app.LeftPanel, 'push');
            app.QuitButton.ButtonPushedFcn = createCallbackFcn(app, @QuitButtonPushed, true);
            app.QuitButton.Text = 'Quit';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Image')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.NetworkPredictionTextAreaLabel = uilabel(app.RightPanel);
            app.NetworkPredictionTextAreaLabel.Text = 'Network Prediction';
            app.NetworkPredictionTextArea = uitextarea(app.RightPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DemoTrainedNetwork

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end